classdef systemModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        SetupTab                   matlab.ui.container.Tab
        InputpulseDropDownLabel    matlab.ui.control.Label
        InputpulseDropDown         matlab.ui.control.DropDown
        transmitterDropDownLabel   matlab.ui.control.Label
        transmitterDropDown        matlab.ui.control.DropDown
        droptobaseworkspaceButton  matlab.ui.control.Button
        SystemTab                  matlab.ui.container.Tab
        SourceLabel                matlab.ui.control.Label
        TButton                    matlab.ui.control.Button
        impulseResponse            matlab.ui.control.UIAxes
        amplitudeResponse          matlab.ui.control.UIAxes
        phaseResponse              matlab.ui.control.UIAxes
        aButton                    matlab.ui.control.Button
        DButton                    matlab.ui.control.Button
        DelayButton                matlab.ui.control.Button
        DelayButton_2              matlab.ui.control.Button
        DelayButton_3              matlab.ui.control.Button
        TagButton                  matlab.ui.control.Button
        CButton                    matlab.ui.control.Button
        UnknownObjectButton        matlab.ui.control.Button
        OButton                    matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        CouplingButton             matlab.ui.control.Button
        TXantennaButton            matlab.ui.control.Button
        TextArea                   matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        data % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: aButton
        function aButtonPushed(app, event)
            app.data.a=getpulse(app.InputpulseDropDown.Value);
            plotresponse(app,app.data.a);
        end

        % Button pushed function: TXantennaButton
        function TXantennaButtonPushed(app, event)
            app.data.transmitterResponse=getTX(app.transmitterDropDown.Value);
            plotresponse(app,app.data.transmitterResponse);
        end

        % Button pushed function: TButton
        function TButtonPushed(app, event)
            app.data.T=getT(app.data);
            plotresponse(app,app.data.T);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'escape'
                    delete(app)
                case '1'
                    app.TabGroup.SelectedTab=app.SetupTab;
                case '2'
                    app.TabGroup.SelectedTab=app.SystemTab;
            end
        end

        % Button pushed function: droptobaseworkspaceButton
        function droptobaseworkspaceButtonPushed(app, event)
            assignin("base",'appdata',app.data)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.InputpulseDropDownLabel = uilabel(app.SetupTab);
            app.InputpulseDropDownLabel.Text = 'Input pulse';
            app.InputpulseDropDown = uidropdown(app.SetupTab);
            app.InputpulseDropDown.Items = {'Impulse', 'short pulse', 'Option 3', 'Option 4'};
            app.InputpulseDropDown.Value = 'Impulse';
            app.transmitterDropDownLabel = uilabel(app.SetupTab);
            app.transmitterDropDownLabel.Text = 'transmitter';
            app.transmitterDropDown = uidropdown(app.SetupTab);
            app.transmitterDropDown.Items = {'gain (1x)', 'gain (2x)', 'option 3'};
            app.transmitterDropDown.Value = 'gain (1x)';
            app.droptobaseworkspaceButton = uibutton(app.SetupTab, 'push');
            app.droptobaseworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @droptobaseworkspaceButtonPushed, true);
            app.droptobaseworkspaceButton.Text = 'drop to base workspace';
            app.SystemTab = uitab(app.TabGroup);
            app.SystemTab.Title = 'System';
            app.SourceLabel = uilabel(app.SystemTab);
            app.SourceLabel.Text = 'Source';
            app.TButton = uibutton(app.SystemTab, 'push');
            app.TButton.ButtonPushedFcn = createCallbackFcn(app, @TButtonPushed, true);
            app.TButton.Text = 'T';
            app.impulseResponse = uiaxes(app.SystemTab);
            title(app.impulseResponse, 'Impulse response')
            xlabel(app.impulseResponse, 'time (samples)')
            ylabel(app.impulseResponse, 'Y')
            app.amplitudeResponse = uiaxes(app.SystemTab);
            title(app.amplitudeResponse, 'Frequency response (amplitude)')
            xlabel(app.amplitudeResponse, 'Frequency')
            ylabel(app.amplitudeResponse, '|Y|')
            app.phaseResponse = uiaxes(app.SystemTab);
            title(app.phaseResponse, 'Frequency response (angle)')
            xlabel(app.phaseResponse, 'Frequency')
            ylabel(app.phaseResponse, '\angleY')
            app.aButton = uibutton(app.SystemTab, 'push');
            app.aButton.ButtonPushedFcn = createCallbackFcn(app, @aButtonPushed, true);
            app.aButton.Text = 'a';
            app.CouplingButton = uibutton(app.SystemTab, 'push');
            app.CouplingButton.Text = 'Coupling';
            app.TXantennaButton = uibutton(app.SystemTab, 'push');
            app.TXantennaButton.ButtonPushedFcn = createCallbackFcn(app, @TXantennaButtonPushed, true);
            app.TXantennaButton.Text = 'TX antenna';
            app.DButton = uibutton(app.SystemTab, 'push');
            app.DButton.Text = 'D';
            app.DelayButton = uibutton(app.SystemTab, 'push');
            app.DelayButton.Text = 'Delay';
            app.DelayButton_2 = uibutton(app.SystemTab, 'push');
            app.DelayButton_2.Text = 'Delay';
            app.DelayButton_3 = uibutton(app.SystemTab, 'push');
            app.DelayButton_3.Text = 'Delay';
            app.TagButton = uibutton(app.SystemTab, 'push');
            app.TagButton.Text = 'Tag';
            app.CButton = uibutton(app.SystemTab, 'push');
            app.CButton.Text = 'C';
            app.UnknownObjectButton = uibutton(app.SystemTab, 'push');
            app.UnknownObjectButton.Text = {'Unknown'; 'Object'};
            app.OButton = uibutton(app.SystemTab, 'push');
            app.OButton.Text = 'O';
            app.Label = uilabel(app.SystemTab);
            app.Label.Text = {'------------------------------------------------------------------'; '            |                                   |                              |'};
            app.TextArea = uitextarea(app.SystemTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = systemModel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end